classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InverseButton         matlab.ui.control.Button
        ZEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        Theta6EditField       matlab.ui.control.NumericEditField
        Theta6EditFieldLabel  matlab.ui.control.Label
        Theta5EditField       matlab.ui.control.NumericEditField
        Theta5EditFieldLabel  matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        Theta4EditField       matlab.ui.control.NumericEditField
        Theta4EditFieldLabel  matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        ForwardButton         matlab.ui.control.Button
        LoadRobotButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        modelRobot % loaded robot
        qz = [0     0     0     0     0     0];
        q = [pi/2	pi/2  -pi/2	0	0	0];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axes(app.UIAxes);
            cla(app.UIAxes)
            ax = gca;
            L1 = Link('d',0.275,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L2 = Link('d',0,'a',0.7,'alpha',-pi,'offset',-pi/2,'qlim',[deg2rad(-90),deg2rad(90)]);
            L3 = Link('d',0,'a',0.5,'alpha',pi,'offset',0,'qlim',[deg2rad(-170),deg2rad(170)]);
            L4 = Link('d',0.162,'a',0,'alpha',-pi/2,'offset',-pi/2,'qlim',[deg2rad(-360),deg2rad(360)]);
            L5 = Link('d',0.170,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L6 = Link('d',0,'a',0,'alpha',0,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            app.modelRobot = SerialLink([L1 L2 L3 L4 L5 L6],'name','MOTOMAN HC10DTP');
            plot(app.modelRobot,app.q)

            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];

            drawnow();

        end

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)
%             qmatrix = jtraj(app.q,app.qz,20);
%             for robotStepIndex = 1:size(qmatrix,1)
% 
%                 % Since findobj in animate dosen't work then we have to
%                 % find the handle ourselves
%                 handle = [];
%                 for childIndex = 1:length(app.UIAxes.Children)
%                     if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
%                         handle = app.UIAxes.Children(childIndex);
%                     end
%                 end
% 
%                 % Plot it by moving the handle of the robot
%                 animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
%                 drawnow
%             end

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %clear existing ui
            %             cla(app.UIAxes)

            %get values from textboxs
            th_1_ = app.Theta1EditField.Value;
            th_2_ = app.Theta2EditField.Value;
            th_3_ = app.Theta3EditField.Value;
            th_4_ = app.Theta4EditField.Value;
            th_5_ = app.Theta5EditField.Value;
            th_6_ = app.Theta6EditField.Value;

            %change degrees to radians
            qe = [th_1_*pi/180, th_2_*pi/180, th_3_*pi/180, th_4_*pi/180, th_5_*pi/180, th_6_*pi/180];

            %             ax = gca;
            %             plot(app.modelRobot,qe)
            %
            %             while 0 < length(ax.Children)
            %                 set(ax.Children(1),'Parent',app.UIAxes);
            %             end
            %             delete(get(gca,'parent'));
            %make some container to store previous value of q

            qmatrix = jtraj(app.q,qe,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)                    
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end

        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            %             cla(app.UIAxes)
            x_= app.XEditField.Value;
            y_= app.YEditField.Value;
            z_= app.ZEditField.Value;
            T1 = transl(x_,y_,z_);                                                  % Create translation matrix
            q1 = app.modelRobot.ikine(T1);
            %             ax = gca;
            %             plot(app.modelRobot,q1)
            %
            %             while 0 < length(ax.Children)
            %                 set(ax.Children(1),'Parent',app.UIAxes);
            %             end
            %             delete(get(gca,'parent'));

            qmatrix = jtraj(app.q,q1,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end

        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            value = app.Theta1EditField.Value;
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            value = app.Theta2EditField.Value;
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            value = app.Theta3EditField.Value;
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            value = app.Theta4EditField.Value;
        end

        % Value changed function: Theta5EditField
        function Theta5EditFieldValueChanged(app, event)
            value = app.Theta5EditField.Value;
        end

        % Value changed function: Theta6EditField
        function Theta6EditFieldValueChanged(app, event)
            value = app.Theta6EditField.Value;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadRobotButton = uibutton(app.UIFigure, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta 5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.ValueChangedFcn = createCallbackFcn(app, @Theta5EditFieldValueChanged, true);
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta 6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.ValueChangedFcn = createCallbackFcn(app, @Theta6EditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end