classdef final_1_collision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        objecttellEditField       matlab.ui.control.EditField
        objecttellEditFieldLabel  matlab.ui.control.Label
        deleteobjectButton        matlab.ui.control.Button
        ZButton_2                 matlab.ui.control.Button
        ZButton                   matlab.ui.control.Button
        YButton_2                 matlab.ui.control.Button
        YButton                   matlab.ui.control.Button
        XButton_2                 matlab.ui.control.Button
        XButton                   matlab.ui.control.Button
        Z_EditField               matlab.ui.control.NumericEditField
        Z_EditFieldLabel          matlab.ui.control.Label
        Y_EditField               matlab.ui.control.NumericEditField
        Y_EditFieldLabel          matlab.ui.control.Label
        X_EditField               matlab.ui.control.NumericEditField
        X_EditFieldLabel          matlab.ui.control.Label
        ActivecontrolButton       matlab.ui.control.StateButton
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        Slider6                   matlab.ui.control.Slider
        Slider6Label              matlab.ui.control.Label
        Slider5                   matlab.ui.control.Slider
        Slider5Label              matlab.ui.control.Label
        Slider4                   matlab.ui.control.Slider
        Slider4Label              matlab.ui.control.Label
        Slider3                   matlab.ui.control.Slider
        Slider3Label              matlab.ui.control.Label
        Slider2                   matlab.ui.control.Slider
        Slider2Label              matlab.ui.control.Label
        Slider1                   matlab.ui.control.Slider
        Slider1Label              matlab.ui.control.Label
        FeedbackTextArea          matlab.ui.control.TextArea
        FeedbackTextAreaLabel     matlab.ui.control.Label
        placeobejctinlightcurtainButton  matlab.ui.control.Button
        AddlightcurtainButton     matlab.ui.control.Button
        ResumeButton              matlab.ui.control.Button
        DorandommoveButton        matlab.ui.control.Button
        InverseButton             matlab.ui.control.Button
        XEditField                matlab.ui.control.NumericEditField
        Theta6EditField           matlab.ui.control.NumericEditField
        Theta6EditFieldLabel      matlab.ui.control.Label
        Theta5EditField           matlab.ui.control.NumericEditField
        Theta5EditFieldLabel      matlab.ui.control.Label
        Theta1EditField           matlab.ui.control.NumericEditField
        Theta1EditFieldLabel      matlab.ui.control.Label
        Theta4EditField           matlab.ui.control.NumericEditField
        Theta4EditFieldLabel      matlab.ui.control.Label
        Theta3EditField           matlab.ui.control.NumericEditField
        Theta3EditFieldLabel      matlab.ui.control.Label
        Theta2EditField           matlab.ui.control.NumericEditField
        Theta2EditFieldLabel      matlab.ui.control.Label
        ForwardButton             matlab.ui.control.Button
        EstopButton               matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        modelRobot % loaded robot
        qz = [0     0     0     0     0     0];
        q = [0 	0   0	0	0	0];
        Estop   %fot estop 1 = stop, 0 is contiue
        Estop_ = 5;
        cubePoints
        pointsInside
        increament %for forward function
        increament_ % for inverse function
        increament__
        current_pose %save current pose
        typed_pose %save typed pose
        q0 = [0     0     0     0     0     0];
        q1
        q2
        h
        h2


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             axes(app.UIAxes);
            %             cla(app.UIAxes)
            %             ax = gca;

            %DH parameters
            L1 = Link('d',0.275,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L2 = Link('d',0,'a',0.7,'alpha',0,'offset',pi/2,'qlim',[deg2rad(-90),deg2rad(90)]);

            L3 = Link('d',0,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-170),deg2rad(170)]);

            L4 = Link('d',0.5,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L5 = Link('d',0.162,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L6 = Link('d',0.170,'a',0,'alpha',0,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            app.modelRobot = SerialLink([L1 L2 L3 L4 L5 L6],'name','MOTOMAN HC10DTP');
            %% Environment Setup
            app.modelRobot.base=transl(0,0,0.5);

            workspace = [-2.5 2.5 -2.5 2.5 -0.05 2.5];

            %% Environment Setup

            workspace = [-2.5 2.5 -2.5 2.5 -0.05 2.5];

            tablePosition = [0 2 0];
            tablePose = transl(tablePosition);
            tableMesh_h = PlaceObject('dishwasherBottom_d.ply');
            tableVertices = get(tableMesh_h,'Vertices');
            tableTransformedVertices = [tableVertices,ones(size(tableVertices,1),1)] * tablePose';
            set(tableMesh_h,'Vertices',tableTransformedVertices(:,1:3));
            camlight;
            % scale = 0.5;
            axis equal

            hold on;

            basePosition = [1 2 0];
            basePose = transl(basePosition);
            baseMesh_h = PlaceObject('newTable.ply');
            baseVertices = get(baseMesh_h,'Vertices');
            baseTransformedVertices = [baseVertices,ones(size(baseVertices,1),1)] * basePose';
            set(baseMesh_h,'Vertices',baseTransformedVertices(:,1:3));
            %
            rackPosition = [0 1.92 1];
            rackPose = transl(rackPosition);
            rackMesh_h = PlaceObject('dishTray.ply');
            rackVertices = get(rackMesh_h,'Vertices');
            rackTransformedVertices = [rackVertices,ones(size(rackVertices,1),1)] * rackPose';
            set(rackMesh_h,'Vertices',rackTransformedVertices(:,1:3));

            lidPosition = [-0.11 1.8 1.5];
            lidPose = transl(lidPosition);
            lidMesh_h = PlaceObject('toplid_d.ply');
            lidVertices = get(lidMesh_h,'Vertices');
            lidTransformedVertices = [lidVertices,ones(size(lidVertices,1),1)] * lidPose';
            set(lidMesh_h,'Vertices',lidTransformedVertices(:,1:3));

            armPosition = [0 0 0];
            armPose = transl(armPosition);
            armMesh_h = PlaceObject('smallTable_d.ply');
            armVertices = get(armMesh_h,'Vertices');
            armTransformedVertices = [armVertices,ones(size(armVertices,1),1)] * armPose';
            set(armMesh_h,'Vertices',armTransformedVertices(:,1:3));

            fencePosition = [0.5 -2 0];
            fencePose = transl(fencePosition);
            fenceMesh_h = PlaceObject('fence.ply');
            fenceVertices = get(fenceMesh_h,'Vertices');
            fenceTransformedVertices = [fenceVertices,ones(size(fenceVertices,1),1)] * fencePose';
            set(fenceMesh_h,'Vertices',fenceTransformedVertices(:,1:3));

            fence2Position = [-1 0 0];
            fence2Pose = transl(fence2Position)  * trotz(pi/2) ;
            fence2Mesh_h = PlaceObject('fence.ply');
            fence2Vertices = get(fence2Mesh_h,'Vertices');
            fence2TransformedVertices = [fence2Vertices,ones(size(fence2Vertices,1),1)] * fence2Pose';
            set(fence2Mesh_h,'Vertices',fence2TransformedVertices(:,1:3));

            fireExtPosition = [2 2 0];
            fireExtPose = transl(fireExtPosition)  * trotz(pi/2) ;
            fireExtMesh_h = PlaceObject('fireExtinguisher.ply');
            fireExtVertices = get(fireExtMesh_h,'Vertices');
            fireExtTransformedVertices = [fireExtVertices,ones(size(fireExtVertices,1),1)] * fireExtPose';
            set(fireExtMesh_h,'Vertices',fireExtTransformedVertices(:,1:3));

            % firePosition = [2 2 0.35];
            % PutObject('fireExtinguisher.ply',firePosition);

            firstAidPosition = [2.1 2.5 1.1];
            firstAidPose = transl(firstAidPosition);
            firstAidMesh_h = PlaceObject('firstAid.ply');
            firstAidVertices = get(firstAidMesh_h,'Vertices');
            firstAidTransformedVertices = [firstAidVertices,ones(size(firstAidVertices,1),1)] * firstAidPose';
            set(firstAidMesh_h,'Vertices',firstAidTransformedVertices(:,1:3));

            % firstAidPosition = [2.1 2.5 1.5];
            % PutObject('firstAid.ply',firstAidPosition);

            % eStopPosition = [1 2.2 1];
            % PutObject('eStop.ply',eStopPosition);
            eStopPosition = [1 2.2 0.95];
            eStopPose = transl(eStopPosition);
            eStopMesh_h = PlaceObject('eStop.ply');
            eStopVertices = get(eStopMesh_h,'Vertices');
            eStopTransformedVertices = [eStopVertices,ones(size(eStopVertices,1),1)] * eStopPose';
            set(eStopMesh_h,'Vertices',eStopTransformedVertices(:,1:3));

            % setting up the robot

            %Setup UR3
            moto=motoman(false).model;
            moto.base=transl(0,0,0.5);
            q = zeros(1,6);
            % moto.plot3d(q);
            moto.delay=0;

            % Surface environment setup
            surf([2.5,2.5;2.5,2.5],[-2.5,2.5;-2.5,2.5],[2.5,2.5;0,0],'CData',imread('kitchen2.jpg'),'FaceColor','texturemap');
            surf([-2.5,-2.5;2.5,2.5],[-2.5,2.5;-2.5,2.5],[0.01,0.01;0.01,0.01],'CData',imread('concrete.jpg'),'FaceColor','texturemap');


            %% set up camera

            % %Create 3D points
            % P=[1.8,1.8,1.8,1.8; -0.25,0.25,0.25,-0.25; 1.25,1.25,0.75,0.75];
            %
            % % Add the camera
            % cam = CentralCamera('focal', 0.08, 'pixel', 10e-5, ...
            %     'resolution', [1024 1024], 'centre', [512 512],'name', 'UR10camera');
            %
            % % frame rate
            % fps = 25;
            %
            % %Define values
            % %gain of the controler
            % lambda = 0.6;
            % %depth of the IBVS
            % depth = mean (P(1,:));


            %% 1.2 Initialise Simulation (Display in 3D)

            %Initial pose
            % q0 = [pi/2; -pi/4; -pi/3; -pi/6; 0; 0];

            % Tc0= app.modelRobot.fkine(q0);
            % plot camera and points
            % cam.T = Tc0;
            %
            % % Display points in 3D and the camera
            %  cam.plot_camera(app.uiaxes);
            %  plot_sphere(P, 0.05, 'b')
            % lighting gouraud
            % light
            %
            % while 0 < length(ax.Children)
            %     set(ax.Children(1),'Parent',app.UIAxes);
            % end


            %% for plotting
            %             plot(app.modelRobot,app.q)
            %             while 0 < length(ax.Children)
            %                 set(ax.Children(1),'Parent',app.UIAxes);
            %             end
            %             delete(get(gca,'parent'))

            % Scale the axes sensibly
            %             app.UIAxes.DataAspectRatio = [1,1,1];
            %             app.UIAxes.View = [45,45];
            %

            drawnow();

        end

        % Button pushed function: DorandommoveButton
        function DorandommoveButtonPushed(app, event)
            %get values from textfield
            x_= app.XEditField.Value;
            y_= app.YEditField.Value;
            z_= app.ZEditField.Value;
            dirty = [0.7,-0.5,1.3]
            T1 = transl(dirty);
            q1 = app.modelRobot.ikcon(T1);
            washer = [0,0.7,1.3]
            T2 = transl(washer);
            q2 = app.modelRobot.ikcon(T2);
            drier = [-0.7,-0.5,1.3]
            T3 = transl(drier);
            q3 = app.modelRobot.ikcon(T3);

            qmatrix = jtraj(app.q,q1,30);
            for robotStepIndex = 1:size(qmatrix,1)
                animate(app.modelRobot,qmatrix(robotStepIndex,:))
                drawnow
                pause(0.2)
            end
            %
            qmatrix = jtraj(q1,q2,30);
            for robotStepIndex = 1:size(qmatrix,1)
                animate(app.modelRobot,qmatrix(robotStepIndex,:))
                drawnow
                pause(0.2)
            end


            qmatrix = jtraj(q2,q3,30);
            for robotStepIndex = 1:size(qmatrix,1)
                animate(app.modelRobot,qmatrix(robotStepIndex,:))
                drawnow
                pause(0.2)
            end

        end

        % Button pushed function: EstopButton
        function EstopButtonPushed(app, event)
            %initial value for estop is 5, so when pressed it will go to
            %here
            if app.Estop_ == 5
                estop_text_ = 'E-stop pressed';
                app.FeedbackTextArea.Value = estop_text_;
                uiwait;
                app.Estop_ = 5;
            end

            %when resume is pressed, it will inable to resume
            if app.Estop_ == 10;
                uiresume;
                estop_text_ = 'Resumed';
                app.FeedbackTextArea.Value = estop_text_;
                app.Estop_ = 5;
            end

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            %increments estop_ for resume
            app.Estop_ = app.Estop_ + 5;

            %for displaying text when E-stop is pressed
            estop_text = 'Press       E-stop to Resume';
            app.FeedbackTextArea.Value = estop_text;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %clear existing ui
            %cla(app.UIAxes)
            %get values from textboxs
            th_1_ = app.Theta1EditField.Value;
            th_2_ = app.Theta2EditField.Value;
            th_3_ = app.Theta3EditField.Value;
            th_4_ = app.Theta4EditField.Value;
            th_5_ = app.Theta5EditField.Value;
            th_6_ = app.Theta6EditField.Value;

            %change degrees to radians
            qe = [th_1_*pi/180, th_2_*pi/180, th_3_*pi/180, th_4_*pi/180, th_5_*pi/180, th_6_*pi/180];

            % after the first pose, it will save the 2nd pose
            app.typed_pose = qe;


            % this will happen after first forward
            if app.increament == 2;
                qmatrix = jtraj(app.current_pose,app.typed_pose,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    animate(app.modelRobot,qmatrix(robotStepIndex,:)) %handle)
                    drawnow
                end
            else
                qmatrix = jtraj(app.qz,qe,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    animate(app.modelRobot,qmatrix(robotStepIndex,:))
                    drawnow
                end
            end
            %save the current pose which has been typed first, this is for
            %saving current pose for contiuned motion.
            app.current_pose = qe;
            app.increament = 2;
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            x_= app.XEditField.Value;
            y_= app.YEditField.Value;
            z_= app.ZEditField.Value;
            T1 = transl(x_,y_,z_);                                                  % Create translation matrix
            app.q1 = app.modelRobot.ikcon(T1);
            if app.increament_ == 2
                qmatrix = jtraj(app.q2,app.q1,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    % Plot it by moving the handle of the robot
                    animate(app.modelRobot,qmatrix(robotStepIndex,:))
                    drawnow
                end
            else
                qmatrix = jtraj(app.q0,app.q1,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    animate(app.modelRobot,qmatrix(robotStepIndex,:))
                    drawnow
                end
            end

            app.q2 = app.q1;
            app.increament_ = 2;

        end

        % Button pushed function: AddlightcurtainButton
        function AddlightcurtainButtonPushed(app, event)
            %% make cube
            hold on
            ax = gca;
            % based on lab 6 question 2 solution
            %define sides length
            sides_ = 2;
            %define points size
            point_size = 0.3
            %create 6 6 6 cube
            [Y,Z] = meshgrid(-sides_:point_size:sides_,-sides_:point_size:sides_);
            sizeMat = size(Y);
            X = repmat(sides_,sizeMat(1),sizeMat(2));

            % Combine surface point cloud and
            % put points in global variable
            app.cubePoints = [X(:),Y(:),Z(:)];

            %rotate around a signle side by 90 degrees and around y to make
            %cube faces
            app.cubePoints = [ app.cubePoints ...
                ; app.cubePoints * rotz(pi/2)...
                ; app.cubePoints * rotz(pi) ...
                ; app.cubePoints * rotz(3*pi/2) ...
                ; app.cubePoints * roty(pi/2) ...
                ; app.cubePoints * roty(-pi/2)];

            % Plot cude point clouds ( x= 1, y = 0, z = 1
            app.cubePoints = app.cubePoints + repmat([1,0,1],size(app.cubePoints,1),1);
            cube_h = plot3(app.cubePoints(:,1),app.cubePoints(:,2),app.cubePoints(:,3),'b.');


        end

        % Button pushed function: placeobejctinlightcurtainButton
        function placeobejctinlightcurtainButtonPushed(app, event)
            hold on
            %function based on lab 6 Solution 2
            %get nubers from textfield
            x_= app.X_EditField.Value;
            y_= app.Y_EditField.Value;
            z_= app.Z_EditField.Value;

            %put coordinates according to textfield
            centerPoint = [x_,y_,z_];
            %set radii and create eliposide
            radii = [0.5,0.5,0.5];
            [X,Y,Z] = ellipsoid( centerPoint(1), centerPoint(2), centerPoint(3), radii(1), radii(2), radii(3) );
            app.h = surf(X,Y,Z);

            %using algebraic distances of the points.
            algebraicDist = GetAlgebraicDist(app.cubePoints, centerPoint, radii);
            %find if there is points distance that is less than 1, which
            %means what point is in the circle
            pointsInside = find(algebraicDist < 1);

            display(['2.4: There are ', num2str(size(pointsInside,1)),' points inside']);

            %this function calculates distance of the points,
            function algebraicDist = GetAlgebraicDist(points, centerPoint, radii)
                algebraicDist = ((points(:,1)-centerPoint(1))/radii(1)).^2 ...
                    + ((points(:,2)-centerPoint(2))/radii(2)).^2 ...
                    + ((points(:,3)-centerPoint(3))/radii(3)).^2;
            end


            app.increament__ = 2;

            %% if points inside are larger than 1,
            %make the robot stop, if points are larger than 0
            if  pointsInside > 0
                estop_text_ = 'Obstacle Detected';
                app.FeedbackTextArea.Value = estop_text_;
                %display as textfield
                uiwait;
            end

        end

        % Button pushed function: deleteobjectButton
        function deleteobjectButtonPushed(app, event)
            % delete object and restart
            if app.increament__ == 2
                estop_text_ = 'Obstacle Removed';
                app.FeedbackTextArea.Value = estop_text_;
                delete(app.h)
                uiresume;
            end
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            value = app.Theta1EditField.Value;
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            value = app.Theta2EditField.Value;
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            value = app.Theta3EditField.Value;
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            value = app.Theta4EditField.Value;
        end

        % Value changed function: Theta5EditField
        function Theta5EditFieldValueChanged(app, event)
            value = app.Theta5EditField.Value;
        end

        % Value changed function: Theta6EditField
        function Theta6EditFieldValueChanged(app, event)
            value = app.Theta6EditField.Value;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;

        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;

        end

        % Value changed function: X_EditField
        function X_EditFieldValueChanged(app, event)
            value = app.X_EditField.Value;

        end

        % Value changed function: Y_EditField
        function Y_EditFieldValueChanged(app, event)
            value = app.Y_EditField.Value;

        end

        % Value changed function: Z_EditField
        function Z_EditFieldValueChanged(app, event)
            value = app.Z_EditField.Value;

        end

        % Value changed function: ActivecontrolButton
        function ActivecontrolButtonValueChanged(app, event)
            %get values from textboxs


            % %                 th_2_ = app.Theta2EditField.Value;
            % %                 th_3_ = app.Theta3EditField.Value;
            % %                 th_4_ = app.Theta4EditField.Value;
            % %                 th_5_ = app.Theta5EditField.Value;
            % %                 th_6_ = app.Theta6EditField.Value;
            %
            %                 %change degrees to radians
            %                 qe = [app.changingValue2*180/pi, 0, 0, 0, 0, 0];
            %
            %                 %             ax = gca;
            %                 %             plot(app.modelRobot,qe)
            %                 %
            %                 %             while 0 < length(ax.Children)
            %                 %                 set(ax.Children(1),'Parent',app.UIAxes);
            %                 %             end
            %                 %             delete(get(gca,'parent'));
            %                 %make some container to store previous value of q
            %
            %                 qmatrix = jtraj(app.q,qe,20);
            %                 for robotStepIndex = 1:size(qmatrix,1)
            %                     % Since findobj in animate dosen't work then we have to
            %                     % find the handle ourselves
            %                     handle = [];
            %                     for childIndex = 1:length(app.UIAxes.Children)
            %                         if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
            %                             handle = app.UIAxes.Children(childIndex);
            %                         end
            %                     end
            %
            %                     % Plot it by moving the handle of the robot
            %                     animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
            %                     drawnow
            %
            %                 end


        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(1,4) = tran(1,4) - 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            value = app.Slider1.Value;
            theta=app.modelRobot.getpos;
            theta(1)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(1)=deg2rad(changingValue);
            app.modelRobot.animate(theta);

        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            theta=app.modelRobot.getpos;
            theta(2)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(2)=deg2rad(changingValue);
            app.modelRobot.animate(theta);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            theta=app.modelRobot.getpos;
            theta(3)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
            value = app.Slider4.Value;
            theta=app.modelRobot.getpos;
            theta(4)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            value = app.Slider5.Value;
            theta=app.modelRobot.getpos;
            theta(5)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changed function: Slider6
        function Slider6ValueChanged(app, event)
            value = app.Slider6.Value;
            theta=app.modelRobot.getpos;
            theta(6)=deg2rad(value);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(3)=deg2rad(changingValue);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(4)=deg2rad(changingValue);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(5)=deg2rad(changingValue);
            app.modelRobot.animate(theta);
        end

        % Value changing function: Slider6
        function Slider6ValueChanging(app, event)
            changingValue = event.Value;
            theta=app.modelRobot.getpos;
            theta(6)=deg2rad(changingValue);
            app.modelRobot.animate(theta);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(1,4) = tran(1,4) + 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(2,4) = tran(2,4) - 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(2,4) = tran(2,4) + 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(3,4) = tran(3,4) - 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            theta = app.modelRobot.getpos;
            tran = app.modelRobot.fkine(theta);
            tran(3,4) = tran(3,4) + 0.02;
            newEff = app.modelRobot.ikcon(tran, theta);
            app.modelRobot.animate(newEff);
        end

        % Value changed function: FeedbackTextArea
        function FeedbackTextAreaValueChanged(app, event)
            value = app.FeedbackTextArea.Value;
            
        end

        % Value changed function: objecttellEditField
        function objecttellEditFieldValueChanged(app, event)
            value = app.objecttellEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EstopButton = uibutton(app.UIFigure, 'push');
            app.EstopButton.ButtonPushedFcn = createCallbackFcn(app, @EstopButtonPushed, true);
            app.EstopButton.Text = 'E-stop';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta 5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.ValueChangedFcn = createCallbackFcn(app, @Theta5EditFieldValueChanged, true);
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta 6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.ValueChangedFcn = createCallbackFcn(app, @Theta6EditFieldValueChanged, true);
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.DorandommoveButton = uibutton(app.UIFigure, 'push');
            app.DorandommoveButton.ButtonPushedFcn = createCallbackFcn(app, @DorandommoveButtonPushed, true);
            app.DorandommoveButton.Text = 'Do random move';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.AddlightcurtainButton = uibutton(app.UIFigure, 'push');
            app.AddlightcurtainButton.ButtonPushedFcn = createCallbackFcn(app, @AddlightcurtainButtonPushed, true);
            app.AddlightcurtainButton.Text = 'Add light curtain';
            app.placeobejctinlightcurtainButton = uibutton(app.UIFigure, 'push');
            app.placeobejctinlightcurtainButton.ButtonPushedFcn = createCallbackFcn(app, @placeobejctinlightcurtainButtonPushed, true);
            app.placeobejctinlightcurtainButton.Text = 'place obejct in light curtain';
            app.FeedbackTextAreaLabel = uilabel(app.UIFigure);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.UIFigure);
            app.FeedbackTextArea.ValueChangedFcn = createCallbackFcn(app, @FeedbackTextAreaValueChanged, true);
            app.Slider1Label = uilabel(app.UIFigure);
            app.Slider1Label.Text = 'Slider1';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [-180 180];
            app.Slider1.MajorTicks = [-180 -90 0 90 180];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-180 180];
            app.Slider2.MajorTicks = [-180 -90 0 90 180];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-180 180];
            app.Slider3.MajorTicks = [-180 -90 0 90 180];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-180 180];
            app.Slider4.MajorTicks = [-180 -90 0 90 180];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-180 180];
            app.Slider5.MajorTicks = [-180 -90 0 90 180];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.Slider6Label = uilabel(app.UIFigure);
            app.Slider6Label.Text = 'Slider6';
            app.Slider6 = uislider(app.UIFigure);
            app.Slider6.Limits = [-180 180];
            app.Slider6.MajorTicks = [-180 -90 0 90 180];
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @Slider6ValueChanged, true);
            app.Slider6.ValueChangingFcn = createCallbackFcn(app, @Slider6ValueChanging, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ActivecontrolButton = uibutton(app.UIFigure, 'state');
            app.ActivecontrolButton.ValueChangedFcn = createCallbackFcn(app, @ActivecontrolButtonValueChanged, true);
            app.ActivecontrolButton.Text = 'Active control';
            app.X_EditFieldLabel = uilabel(app.UIFigure);
            app.X_EditFieldLabel.Text = 'X_';
            app.X_EditField = uieditfield(app.UIFigure, 'numeric');
            app.X_EditField.ValueChangedFcn = createCallbackFcn(app, @X_EditFieldValueChanged, true);
            app.Y_EditFieldLabel = uilabel(app.UIFigure);
            app.Y_EditFieldLabel.Text = 'Y_';
            app.Y_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_EditField.ValueChangedFcn = createCallbackFcn(app, @Y_EditFieldValueChanged, true);
            app.Z_EditFieldLabel = uilabel(app.UIFigure);
            app.Z_EditFieldLabel.Text = 'Z_';
            app.Z_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_EditField.ValueChangedFcn = createCallbackFcn(app, @Z_EditFieldValueChanged, true);
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '-X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '+X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '-Y';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '+Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '-Z';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '+Z';
            app.deleteobjectButton = uibutton(app.UIFigure, 'push');
            app.deleteobjectButton.ButtonPushedFcn = createCallbackFcn(app, @deleteobjectButtonPushed, true);
            app.deleteobjectButton.Text = 'delete object';
            app.objecttellEditFieldLabel = uilabel(app.UIFigure);
            app.objecttellEditFieldLabel.Text = 'objecttell';
            app.objecttellEditField = uieditfield(app.UIFigure, 'text');
            app.objecttellEditField.ValueChangedFcn = createCallbackFcn(app, @objecttellEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_1_collision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end