classdef working_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ZButton_2              matlab.ui.control.Button
        ZButton                matlab.ui.control.Button
        YButton_2              matlab.ui.control.Button
        YButton                matlab.ui.control.Button
        XButton_2              matlab.ui.control.Button
        XButton                matlab.ui.control.Button
        clearobjectsButton     matlab.ui.control.Button
        Z_EditField            matlab.ui.control.NumericEditField
        Z_EditFieldLabel       matlab.ui.control.Label
        Y_EditField            matlab.ui.control.NumericEditField
        Y_EditFieldLabel       matlab.ui.control.Label
        X_EditField            matlab.ui.control.NumericEditField
        X_EditFieldLabel       matlab.ui.control.Label
        ActivecontrolButton    matlab.ui.control.StateButton
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditFieldLabel        matlab.ui.control.Label
        Slider_6               matlab.ui.control.Slider
        Slider_6Label          matlab.ui.control.Label
        Slider_5               matlab.ui.control.Slider
        Slider_5Label          matlab.ui.control.Label
        Slider_4               matlab.ui.control.Slider
        Slider_4Label          matlab.ui.control.Label
        Slider_3               matlab.ui.control.Slider
        Slider_3Label          matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Slider_2Label          matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        FeedbackTextArea       matlab.ui.control.TextArea
        FeedbackTextAreaLabel  matlab.ui.control.Label
        placeobejctinlightcurtainButton  matlab.ui.control.Button
        AddlightcurtainButton  matlab.ui.control.Button
        ResumeButton           matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        InverseButton          matlab.ui.control.Button
        XEditField             matlab.ui.control.NumericEditField
        Theta6EditField        matlab.ui.control.NumericEditField
        Theta6EditFieldLabel   matlab.ui.control.Label
        Theta5EditField        matlab.ui.control.NumericEditField
        Theta5EditFieldLabel   matlab.ui.control.Label
        Theta1EditField        matlab.ui.control.NumericEditField
        Theta1EditFieldLabel   matlab.ui.control.Label
        Theta4EditField        matlab.ui.control.NumericEditField
        Theta4EditFieldLabel   matlab.ui.control.Label
        Theta3EditField        matlab.ui.control.NumericEditField
        Theta3EditFieldLabel   matlab.ui.control.Label
        Theta2EditField        matlab.ui.control.NumericEditField
        Theta2EditFieldLabel   matlab.ui.control.Label
        ForwardButton          matlab.ui.control.Button
        EstopButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        modelRobot % loaded robot
        qz = [0     0     0     0     0     0];
        q = [0 	0   0	0	0	0];
        Estop   %fot estop 1 = stop, 0 is contiue
        Estop_ = 5; 
        cubePoints
        pointsInside        
        increament %for forward function
        increament_ % for inverse function
        increament__
        current_pose %save current pose
        typed_pose %save typed pose
        q0 = [0     0     0     0     0     0];
        q1
        q2

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axes(app.UIAxes);
            cla(app.UIAxes)
            ax = gca;

            %DH parameters
            L1 = Link('d',0.275,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L2 = Link('d',0,'a',0.7,'alpha',0,'offset',pi/2,'qlim',[deg2rad(-90),deg2rad(90)]);

            L3 = Link('d',0,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-170),deg2rad(170)]);

            L4 = Link('d',0.5,'a',0,'alpha',pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L5 = Link('d',0.162,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            L6 = Link('d',0.170,'a',0,'alpha',0,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);

            app.modelRobot = SerialLink([L1 L2 L3 L4 L5 L6],'name','MOTOMAN HC10DTP');
% %% Environment Setup
% app.modelRobot.base=transl(-0.13,0.5,0.5);
% 
% workspace = [-2.5 2.5 -2.5 2.5 -0.05 2.5];
% 
% %% Environment Setup
%     tablePosition = [-0.2 -1.15 0];
%     tablePose = transl(tablePosition) * trotz(pi);
%     tableMesh_h = PlaceObject('table.ply');
%     tableVertices = get(tableMesh_h,'Vertices');
%     tableTransformedVertices = [tableVertices,ones(size(tableVertices,1),1)] * tablePose';
%     set(tableMesh_h,'Vertices',tableTransformedVertices(:,1:3));
%     camlight;
%     axis equal
%     
%     hold on;
%     
%     basePosition = [0 1.5 0];
%     basePose = transl(basePosition);
%     baseMesh_h = PlaceObject('dishwasherBottom.ply');
%     baseVertices = get(baseMesh_h,'Vertices');
%     baseTransformedVertices = [baseVertices,ones(size(baseVertices,1),1)] * basePose';
%     set(baseMesh_h,'Vertices',baseTransformedVertices(:,1:3));
%     
%     rackPosition = [0.9 -1.2 1.3];
%     rackPose = transl(rackPosition);
%     rackMesh_h = PlaceObject('dishrack.ply');
%     rackVertices = get(rackMesh_h,'Vertices');
%     rackTransformedVertices = [rackVertices,ones(size(rackVertices,1),1)] * rackPose';
%     set(rackMesh_h,'Vertices',rackTransformedVertices(:,1:3));
%     
%     lidPosition = [0 1.3 1.8];
%     lidPose = transl(lidPosition);
%     lidMesh_h = PlaceObject('toplid.ply');
%     lidVertices = get(lidMesh_h,'Vertices');
%     lidTransformedVertices = [lidVertices,ones(size(lidVertices,1),1)] * lidPose';
%     set(lidMesh_h,'Vertices',lidTransformedVertices(:,1:3));
% 
% % setting up the robot
% 
%Setup UR3
moto=motoman(false).model;
moto.base=transl(0,0.5,0.5);
q = zeros(1,6);
% 
moto.plot3d(q);
moto.delay=0;
% 
% % Surface environment setup
% surf([2.5,2.5;2.5,2.5],[-2.5,2.5;-2.5,2.5],[2.5,2.5;0,0],'CData',imread('kitchen2.jpg'),'FaceColor','texturemap');
% surf([-2.5,-2.5;2.5,2.5],[-2.5,2.5;-2.5,2.5],[0.01,0.01;0.01,0.01],'CData',imread('concrete.jpg'),'FaceColor','texturemap');


%% set up camera

% %Create 3D points
% P=[1.8,1.8,1.8,1.8; -0.25,0.25,0.25,-0.25; 1.25,1.25,0.75,0.75];
% 
% % Add the camera
% cam = CentralCamera('focal', 0.08, 'pixel', 10e-5, ...
%     'resolution', [1024 1024], 'centre', [512 512],'name', 'UR10camera');
% 
% % frame rate
% fps = 25;
% 
% %Define values
% %gain of the controler
% lambda = 0.6;
% %depth of the IBVS
% depth = mean (P(1,:));


%% 1.2 Initialise Simulation (Display in 3D)

%Initial pose
% q0 = [pi/2; -pi/4; -pi/3; -pi/6; 0; 0];

% Tc0= app.modelRobot.fkine(q0);
% plot camera and points
% cam.T = Tc0;
% 
% % Display points in 3D and the camera
%  cam.plot_camera(app.uiaxes);
%  plot_sphere(P, 0.05, 'b')
% lighting gouraud
% light
% 
% while 0 < length(ax.Children)
%     set(ax.Children(1),'Parent',app.UIAxes);
% end


            %% for plotting 
            plot(app.modelRobot,app.q)
            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
            

            drawnow();

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %get values from textfield            
            x_= app.XEditField.Value;
            y_= app.YEditField.Value;
            z_= app.ZEditField.Value;
            dirty = [1,0,0]
            T1 = transl(dirty);                                      
            q1 = app.modelRobot.ikcon(T1);
            washer = [0,1,0]
            T2 = transl(washer);                                                  
            q2 = app.modelRobot.ikcon(T2);
            drier = [0,0,1]
            T3 = transl(drier);                                                 
            q3 = app.modelRobot.ikcon(T3);


            qmatrix = jtraj(app.q,q1,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves

                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end

            qmatrix = jtraj(q1,q2,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end


            qmatrix = jtraj(q2,q3,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end

        end

        % Button pushed function: clearobjectsButton
        function clearobjectsButtonPushed(app, event)
            axes(app.UIAxes);
            cla(app.UIAxes)
            ax = gca;
            %% for plotting 
            plot(app.modelRobot,app.q)
             while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
             end
             delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];

            drawnow();
        end

        % Button pushed function: EstopButton
        function EstopButtonPushed(app, event)
            %initial value for estop is 5, so when pressed it will go to
            %here
            if app.Estop_ == 5
                estop_text_ = 'E-stop pressed';
                app.FeedbackTextArea.Value = estop_text_;
                uiwait;
                app.Estop_ = 5;
            end
            
            %when resume is pressed, it will inable to resume
            if app.Estop_ == 10;
                uiresume;
                estop_text_ = 'Resumed';
                app.FeedbackTextArea.Value = estop_text_;
                app.Estop_ = 5;
            end                      

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
          %increments estop_ for resume
          app.Estop_ = app.Estop_ + 5; 
          
          %for displaying text when E-stop is pressed
          estop_text = 'Press       E-stop to Resume';
          app.FeedbackTextArea.Value = estop_text;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %clear existing ui
            %cla(app.UIAxes)
            %get values from textboxs
            th_1_ = app.Theta1EditField.Value;
            th_2_ = app.Theta2EditField.Value;
            th_3_ = app.Theta3EditField.Value;
            th_4_ = app.Theta4EditField.Value;
            th_5_ = app.Theta5EditField.Value;
            th_6_ = app.Theta6EditField.Value;

            %change degrees to radians
            qe = [th_1_*pi/180, th_2_*pi/180, th_3_*pi/180, th_4_*pi/180, th_5_*pi/180, th_6_*pi/180];

            % after the first pose, it will save the 2nd pose
            app.typed_pose = qe;


            % this will happen after first forward
            if app.increament == 2;
                qmatrix = jtraj(app.current_pose,app.typed_pose,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    % Since findobj in animate dosen't work then we have to
                    % find the handle ourselves
                    handle = [];
                    for childIndex = 1:length(app.UIAxes.Children)
                        if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                            handle = app.UIAxes.Children(childIndex);
                        end
                    end
                    % Plot by moving the handle of the robot
                    animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                    drawnow
                end
            else
                qmatrix = jtraj(app.qz,qe,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    % Since findobj in animate dosen't work then we have to
                    % find the handle ourselves
                    handle = [];
                    for childIndex = 1:length(app.UIAxes.Children)
                        if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                            handle = app.UIAxes.Children(childIndex);
                        end
                    end
                    % Plot by moving the handle of the robot
                    animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                    drawnow
                end
            end
            %save the current pose which has been typed first, this is for
            %saving current pose for contiuned motion.
            app.current_pose = qe;
            app.increament = 2;
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            %             cla(app.UIAxes)
            x_= app.XEditField.Value;
            y_= app.YEditField.Value;
            z_= app.ZEditField.Value;
            
            T1 = transl(x_,y_,z_);                                                  % Create translation matrix
            app.q1 = app.modelRobot.ikcon(T1);
            %             ax = gca;
            %             plot(app.modelRobot,q1)
            %
            %             while 0 < length(ax.Children)
            %                 set(ax.Children(1),'Parent',app.UIAxes);
            %             end
            %             delete(get(gca,'parent'));

            if app.increament_ == 2
                qmatrix = jtraj(app.q2,app.q1,20);
                for robotStepIndex = 1:size(qmatrix,1)
                    % Since findobj in animate dosen't work then we have to
                    % find the handle ourselves
                    handle = [];
                    for childIndex = 1:length(app.UIAxes.Children)
                        if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                            handle = app.UIAxes.Children(childIndex);
                        end
                    end
                    % Plot it by moving the handle of the robot
                    animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                    drawnow
                end
            else
                qmatrix = jtraj(app.q0,app.q1,20);
                for robotStepIndex = 1:size(qmatrix,1)

                    % Since findobj in animate dosen't work then we have to
                    % find the handle ourselves
                    handle = [];
                    for childIndex = 1:length(app.UIAxes.Children)
                        if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                            handle = app.UIAxes.Children(childIndex);
                        end
                    end

                    % Plot it by moving the handle of the robot
                    animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                    drawnow
                end
            end

            app.q2 = app.q1;
            app.increament_ = 2;

        end

        % Button pushed function: AddlightcurtainButton
        function AddlightcurtainButtonPushed(app, event)
            %% make cube
            ax = gca; 
            % based on lab 6 question 2 solution
            %define sides length
            sides_ = 2;
            %define points size
            point_size = 0.3
            %create 6 6 6 cube 
            [Y,Z] = meshgrid(-sides_:point_size:sides_,-sides_:point_size:sides_);
            sizeMat = size(Y);
            X = repmat(sides_,sizeMat(1),sizeMat(2));            

            % Combine surface point cloud and 
            % put points in global variable
            app.cubePoints = [X(:),Y(:),Z(:)];

            %rotate around a signle side by 90 degrees and around y to make
            %cube faces
            app.cubePoints = [ app.cubePoints ...
                ; app.cubePoints * rotz(pi/2)...
                ; app.cubePoints * rotz(pi) ...
                ; app.cubePoints * rotz(3*pi/2) ...
                ; app.cubePoints * roty(pi/2) ...
                ; app.cubePoints * roty(-pi/2)];

            % Plot cude point clouds
            app.cubePoints = app.cubePoints + repmat([0,0,0],size(app.cubePoints,1),1);
            cube_h = plot3(app.cubePoints(:,1),app.cubePoints(:,2),app.cubePoints(:,3),'b.');
            
            %display through ui           
            set(ax.Children(1),'Parent',app.UIAxes);
            delete(get(gca,'parent'))

            
        end

        % Button pushed function: placeobejctinlightcurtainButton
        function placeobejctinlightcurtainButtonPushed(app, event)
            
            %function based on lab 6 Solution 2


            ax = gca;            
            %get nubers from textfield
            x_= app.X_EditField.Value;
            y_= app.Y_EditField.Value;
            z_= app.Z_EditField.Value;
            
            %put coordinates according to textfield
            centerPoint = [x_,y_,z_];
            
            s = 10;
            H = zeros(s);

            %set radii and create eliposide
            radii = [0.5,0.5,0.5];
            [X,Y,Z] = ellipsoid( centerPoint(1), centerPoint(2), centerPoint(3), radii(1), radii(2), radii(3) );            
            create_elipsoide = surf(X,Y,Z);  

            set(ax.Children(1),'Parent',app.UIAxes);
             delete(get(gca,'parent'))

            %using algebraic distances of the points.
            algebraicDist = GetAlgebraicDist(app.cubePoints, centerPoint, radii);
            
            %find if there is points distance that is less than 1, which
            %means what point is in the circle
            app.pointsInside = find(algebraicDist < 1);

            display(['collision at light curtain']);

            %this function calculates distance of the points,
            function algebraicDist = GetAlgebraicDist(points, centerPoint, radii)
                algebraicDist = ((points(:,1)-centerPoint(1))/radii(1)).^2 ...
                    + ((points(:,2)-centerPoint(2))/radii(2)).^2 ...
                    + ((points(:,3)-centerPoint(3))/radii(3)).^2;
            end
            
            if algebraicDist == 0


            end   

            %% if points inside are larger than 1,
            %make the robot stop, if points are larger than 0
            if  app.pointsInside  > 0
                    uiwait;
                    delete(get(gca,'parent'))
            end
            %make the robot move, if points are less than or equal to 0
            if app.pointsInside <= 0
                uiresume;
                delete(get(gca,'parent'))                
            end
            app.increament__ = 2

        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            value = app.Theta1EditField.Value;
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            value = app.Theta2EditField.Value;
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            value = app.Theta3EditField.Value;
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            value = app.Theta4EditField.Value;
        end

        % Value changed function: Theta5EditField
        function Theta5EditFieldValueChanged(app, event)
            value = app.Theta5EditField.Value;
        end

        % Value changed function: Theta6EditField
        function Theta6EditFieldValueChanged(app, event)
            value = app.Theta6EditField.Value;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            
        end

        % Value changed function: X_EditField
        function X_EditFieldValueChanged(app, event)
            value = app.X_EditField.Value;
            
        end

        % Value changed function: Y_EditField
        function Y_EditFieldValueChanged(app, event)
            value = app.Y_EditField.Value;
            
        end

        % Value changed function: Z_EditField
        function Z_EditFieldValueChanged(app, event)
            value = app.Z_EditField.Value;
            
        end

        % Value changed function: ActivecontrolButton
        function ActivecontrolButtonValueChanged(app, event)
            %get values from textboxs
          
                
            % %                 th_2_ = app.Theta2EditField.Value;
            % %                 th_3_ = app.Theta3EditField.Value;
            % %                 th_4_ = app.Theta4EditField.Value;
            % %                 th_5_ = app.Theta5EditField.Value;
            % %                 th_6_ = app.Theta6EditField.Value;
            %
            %                 %change degrees to radians
            %                 qe = [app.changingValue2*180/pi, 0, 0, 0, 0, 0];
            %
            %                 %             ax = gca;
            %                 %             plot(app.modelRobot,qe)
            %                 %
            %                 %             while 0 < length(ax.Children)
            %                 %                 set(ax.Children(1),'Parent',app.UIAxes);
            %                 %             end
            %                 %             delete(get(gca,'parent'));
            %                 %make some container to store previous value of q
            %
            %                 qmatrix = jtraj(app.q,qe,20);
            %                 for robotStepIndex = 1:size(qmatrix,1)
            %                     % Since findobj in animate dosen't work then we have to
            %                     % find the handle ourselves
            %                     handle = [];
            %                     for childIndex = 1:length(app.UIAxes.Children)
            %                         if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
            %                             handle = app.UIAxes.Children(childIndex);
            %                         end
            %                     end
            %
            %                     % Plot it by moving the handle of the robot
            %                     animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
            %                     drawnow
            %
            %                 end


        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EstopButton = uibutton(app.UIFigure, 'push');
            app.EstopButton.ButtonPushedFcn = createCallbackFcn(app, @EstopButtonPushed, true);
            app.EstopButton.Text = 'E-stop';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta 5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.ValueChangedFcn = createCallbackFcn(app, @Theta5EditFieldValueChanged, true);
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta 6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.ValueChangedFcn = createCallbackFcn(app, @Theta6EditFieldValueChanged, true);
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.AddlightcurtainButton = uibutton(app.UIFigure, 'push');
            app.AddlightcurtainButton.ButtonPushedFcn = createCallbackFcn(app, @AddlightcurtainButtonPushed, true);
            app.AddlightcurtainButton.Text = 'Add light curtain';
            app.placeobejctinlightcurtainButton = uibutton(app.UIFigure, 'push');
            app.placeobejctinlightcurtainButton.ButtonPushedFcn = createCallbackFcn(app, @placeobejctinlightcurtainButtonPushed, true);
            app.placeobejctinlightcurtainButton.Text = 'place obejct in light curtain';
            app.FeedbackTextAreaLabel = uilabel(app.UIFigure);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = 'Slider';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = 'Slider';
            app.Slider_6 = uislider(app.UIFigure);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ActivecontrolButton = uibutton(app.UIFigure, 'state');
            app.ActivecontrolButton.ValueChangedFcn = createCallbackFcn(app, @ActivecontrolButtonValueChanged, true);
            app.ActivecontrolButton.Text = 'Active control';
            app.X_EditFieldLabel = uilabel(app.UIFigure);
            app.X_EditFieldLabel.Text = 'X_';
            app.X_EditField = uieditfield(app.UIFigure, 'numeric');
            app.X_EditField.ValueChangedFcn = createCallbackFcn(app, @X_EditFieldValueChanged, true);
            app.Y_EditFieldLabel = uilabel(app.UIFigure);
            app.Y_EditFieldLabel.Text = 'Y_';
            app.Y_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_EditField.ValueChangedFcn = createCallbackFcn(app, @Y_EditFieldValueChanged, true);
            app.Z_EditFieldLabel = uilabel(app.UIFigure);
            app.Z_EditFieldLabel.Text = 'Z_';
            app.Z_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_EditField.ValueChangedFcn = createCallbackFcn(app, @Z_EditFieldValueChanged, true);
            app.clearobjectsButton = uibutton(app.UIFigure, 'push');
            app.clearobjectsButton.ButtonPushedFcn = createCallbackFcn(app, @clearobjectsButtonPushed, true);
            app.clearobjectsButton.Text = 'clear objects';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '-X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.Text = '+X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.Text = '-Y';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.Text = '+Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.Text = '-Z';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.Text = 'Z+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = working_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end